classdef run_part2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label             matlab.ui.control.Label
        TextArea          matlab.ui.control.TextArea
        Label_2           matlab.ui.control.Label
        EditField         matlab.ui.control.NumericEditField
        f0Label           matlab.ui.control.Label
        f0EditField       matlab.ui.control.NumericEditField
        f1EditFieldLabel  matlab.ui.control.Label
        f1EditField       matlab.ui.control.NumericEditField
        Button            matlab.ui.control.Button
        Label_3           matlab.ui.control.Label
        TextArea_2        matlab.ui.control.TextArea
        Button_2          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            str_to_send = char(app.TextArea.Value);
            packet_size = app.EditField.Value;
            len = length(str_to_send);
            disp(len);
            for i=1:packet_size:len
                cur_str = str_to_send(i:min(i+packet_size-1,len));
                send_str(cur_str,app.f0EditField.Value,app.f1EditField.Value,1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '待发送文本';
            app.TextArea = uitextarea(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '数据包字节数';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [1 Inf];
            app.EditField.Value = 1;
            app.f0Label = uilabel(app.UIFigure);
            app.f0Label.Text = '频率f0';
            app.f0EditField = uieditfield(app.UIFigure, 'numeric');
            app.f0EditField.Limits = [1 Inf];
            app.f0EditField.Value = 4000;
            app.f1EditFieldLabel = uilabel(app.UIFigure);
            app.f1EditFieldLabel.Text = '频率f1';
            app.f1EditField = uieditfield(app.UIFigure, 'numeric');
            app.f1EditField.Limits = [1 Inf];
            app.f1EditField.Value = 6000;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '发送文本';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '接收到的文本';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '接收文本';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = run_part2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end